classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        StartSimButton            matlab.ui.control.Button
        StopSimButton             matlab.ui.control.Button
        FastSLAMGUILabel          matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        SensornoiseLabel          matlab.ui.control.Label
        RangeEditFieldLabel       matlab.ui.control.Label
        RangeEditField            matlab.ui.control.NumericEditField
        AngularEditFieldLabel     matlab.ui.control.Label
        AngularEditField          matlab.ui.control.NumericEditField
        OdometrynoiseLabel        matlab.ui.control.Label
        velocityEditFieldLabel    matlab.ui.control.Label
        velocityEditField         matlab.ui.control.NumericEditField
        omegaEditFieldLabel       matlab.ui.control.Label
        omegaEditField            matlab.ui.control.NumericEditField
        PredictionnoiseLabel      matlab.ui.control.Label
        velocityEditField_2Label  matlab.ui.control.Label
        velocityEditField_2       matlab.ui.control.NumericEditField
        omegaEditField_2Label     matlab.ui.control.Label
        omegaEditField_2          matlab.ui.control.NumericEditField
        RobotPathDropDownLabel    matlab.ui.control.Label
        RobotPathDropDown         matlab.ui.control.DropDown
        NParticlesSliderLabel     matlab.ui.control.Label
        NParticlesSlider          matlab.ui.control.Slider
        ShowdiagnosticsCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        stop = false;
        simIsRunning = false;
        NParticles = 1000;
        runDiagnostics = false;
        sigmaRangeZ = 0;
        sigmaThetaZ = 0;
        sigmaVOdom = 0;
        sigmaOmegaOdom = 0;
        sigmaVPredict = 0;
        sigmaOmegaPredict = 0;
        robotPath = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            app.simIsRunning = true;
            p = load_waypoints(app.robotPath);
            map = load_map();
            fprintf('Starting simulation with %d particles.\n',app.NParticles)
            nSplines = size(p,2)-3;
            % Initialize plots
            hold(app.UIAxes,"on")
            hold(app.UIAxes2, "on")
            colormap(app.UIAxes,"gray")
            colormap(app.UIAxes2,"gray")
            axis(app.UIAxes,[-1 17 -1 15])
            axis(app.UIAxes2,[-1 17 -1 15])
            f = matfile(fullfile(fileparts(fileparts(mfilename('fullpath'))),'testing','M_test.mat'));
            gtMap = f.M;
            image(app.UIAxes, [0,16], [0,14], mat2gray(squeeze(-gtMap),[-127,127])*255, 'AlphaData', 1, 'CDataMapping', 'direct');
            % Set axis to gray for map display later on.
            
            X = ones(4,app.NParticles);
            % Initialize map and particles with a first scan.
            M = zeros(140,160,app.NParticles);
            [startPosition, ~] = catmull_spline(p(:,1), p(:,2), p(:,3), p(:,4),0.05);
            % Obtain scan at current position, add noise.
            X(1:3,:) = repmat(startPosition,1,app.NParticles);
            z = scan_sim(startPosition,map);
            M = updated_occupancy_grid(X, z, M, 1:app.NParticles);
            X(4,:) = 1/app.NParticles;
            for i = 1:nSplines
                p0 = p(:,i);
                p1 = p(:,i+1);
                p2 = p(:,i+2);
                p3 = p(:,i+3);
                for t = 0.05:0.1:0.95
                    tTot = tic;
                    % Get current robot position and odometry from spline.
                    [pose,vel] = catmull_spline(p0, p1, p2, p3, t);
                    v = vecnorm(vel(1:2)) + normrnd(0,app.sigmaVOdom^2);
                    omega = vel(3) + normrnd(0,app.sigmaOmegaOdom^2);
                    % Plotting the path in ground truth map
                    scatter(app.UIAxes, pose(1),pose(2),'Marker', 'x', 'MarkerEdgeColor', 'k');
                    
                    % Obtain scan at current position, add noise.
                    z = scan_sim(pose,map);
                    z(1,:) = z(1,:) + normrnd(0,app.sigmaRangeZ^2,size(z(1,:)));
                    z(2,:) = mapAngle(z(2,:) + normrnd(0,app.sigmaThetaZ^2,size(z(2,:))));
                    % Plot scan in ground truth map.
                    zViz = z(:,~any(isnan(z) | isinf(z),1));  % Filter out invalid measurements.
                    zViz(2,:) = mod(zViz(2,:) + pose(3) + pi,2*pi) - pi;
                    scan_pos = [pose(1) + cos(zViz(2,:)).*zViz(1,:);pose(2) + sin(zViz(2,:)).*zViz(1,:)];
                    if exist('scan_plt','var')
                        delete(scan_plt)
                    end
                    scan_plt = scatter(app.UIAxes, scan_pos(1,:),scan_pos(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[1 .0 .0],'LineWidth',0.01);
                    
                    % FastSLAM algorithm
                    tMotion = tic;
                    X = sample_motion_model(v,omega,X, app.sigmaVPredict, app.sigmaOmegaPredict);
                    tMotion = toc(tMotion);
                    tZ = tic;
                    X = measurement_model_likelyhood_fields(X, z, M);
                    tZ = toc(tZ);
                    tSampling = tic;
                    [X,sampleIdx] = resampling(X);
                    tSampling = toc(tSampling);
                    tMap = tic;
                    M = updated_occupancy_grid(X, z, M, sampleIdx);
                    tMap = toc(tMap);
                    % Plot map estimate of most likely particle.
                    [~, idx] = max(X(4,:));  % Most likely map.
                    % Minus M because high probabilities are associated
                    % with walls, but should be black in image.
                    im = image(app.UIAxes2, [0,16], [0,14], mat2gray(squeeze(-M(:,:,idx)),[-127,127])*255, 'AlphaData', 0.8, 'CDataMapping', 'direct');
                    if exist('particle_plt','var')
                        delete(particle_plt)
                    end
                    particle_plt = scatter(app.UIAxes2, X(1,:),X(2,:), 'filled', 'MarkerEdgeColor',[0 0 0],...
                          'MarkerFaceColor',[.0 1.0 .0],'LineWidth',0.01);
                    drawnow;
                    tTot = toc(tTot);
                    if app.runDiagnostics
                        fprintf('Loop times: \n  - motion sample: %.2f s\n  - measurement model: %.2f s\n  - map update: %.2f s\n  - resampling: %.2f s\n  - total: %.2f s\n\n',tMotion,tZ,tMap,tSampling,tTot)
                    end
                    if ~((i == nSplines) && (t >= 0.9))
                        delete(im)
                    end
                    if app.stop == true
                        break
                    end
                end
                if app.stop == true
                    break
                end
            end
            app.stop = false;
            app.simIsRunning = false;
            disp('Finished simulation.')
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            app.stop = true;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            hold(app.UIAxes,"on")
            hold(app.UIAxes2, "on")
            axis(app.UIAxes,[-1 17 -1 15])
            axis(app.UIAxes2,[-1 17 -1 15])
            colormap(app.UIAxes,"gray")
            f = matfile(fullfile(fileparts(fileparts(mfilename('fullpath'))),'testing','M_test.mat'));
            gtMap = f.M;
            image(app.UIAxes, [0,16], [0,14], mat2gray(squeeze(-gtMap),[-127,127])*255, 'AlphaData', 1, 'CDataMapping', 'direct');
            if ~app.simIsRunning
                app.stop = false;
            end
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            value = app.RangeEditField.Value;
            if value >= 0
                app.sigmaRangeZ = value;
            else
                app.RangeEditField.Value = app.sigmaRangeZ;
            end
        end

        % Value changed function: AngularEditField
        function AngularEditFieldValueChanged(app, event)
            value = app.AngularEditField.Value;
            if value >= 0
                app.sigmaThetaZ = value;
            else
                app.RangeEditField.Value = app.sigmaThetaZ;
            end
        end

        % Value changed function: velocityEditField
        function velocityEditFieldValueChanged(app, event)
            value = app.velocityEditField.Value;
            if value >= 0
                app.sigmaVOdom = value;
            else
                app.RangeEditField.Value = app.sigmaVOdom;
            end
        end

        % Value changed function: omegaEditField
        function omegaEditFieldValueChanged(app, event)
            value = app.omegaEditField.Value;
            if value >= 0
                app.sigmaOmegaOdom = value;
            else
                app.RangeEditField.Value = app.sigmaOmegaOdom;
            end
        end

        % Value changed function: velocityEditField_2
        function velocityEditField_2ValueChanged(app, event)
            value = app.velocityEditField_2.Value;
            if value >= 0
                app.sigmaVPredict = value;
            else
                app.RangeEditField.Value = app.sigmaVPredict;
            end
        end

        % Value changed function: omegaEditField_2
        function omegaEditField_2ValueChanged(app, event)
            value = app.omegaEditField_2.Value;
            if value >= 0
                app.sigmaOmegaPredict = value;
            else
                app.RangeEditField.Value = app.sigmaOmegaPredict;
            end
        end

        % Value changed function: RobotPathDropDown
        function RobotPathDropDownValueChanged(app, event)
            value = app.RobotPathDropDown.Value;
            app.robotPath = str2num(value);
        end

        % Value changed function: NParticlesSlider
        function NParticlesSliderValueChanged(app, event)
            value = app.NParticlesSlider.Value;
            app.NParticles = int16(value);
        end

        % Value changed function: ShowdiagnosticsCheckBox
        function ShowdiagnosticsCheckBoxValueChanged(app, event)
            value = app.ShowdiagnosticsCheckBox.Value;
            app.runDiagnostics = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map and Robot Position')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartSimButton = uibutton(app.UIFigure, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.StopSimButton = uibutton(app.UIFigure, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.FastSLAMGUILabel = uilabel(app.UIFigure);
            app.FastSLAMGUILabel.Text = 'FastSLAM GUI';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Estimated map')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SensornoiseLabel = uilabel(app.UIFigure);
            app.SensornoiseLabel.Text = 'Sensor noise';
            app.RangeEditFieldLabel = uilabel(app.UIFigure);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.AngularEditFieldLabel = uilabel(app.UIFigure);
            app.AngularEditFieldLabel.Text = 'Angular';
            app.AngularEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngularEditField.ValueChangedFcn = createCallbackFcn(app, @AngularEditFieldValueChanged, true);
            app.OdometrynoiseLabel = uilabel(app.UIFigure);
            app.OdometrynoiseLabel.Text = 'Odometry noise';
            app.velocityEditFieldLabel = uilabel(app.UIFigure);
            app.velocityEditFieldLabel.Text = 'velocity';
            app.velocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.velocityEditField.ValueChangedFcn = createCallbackFcn(app, @velocityEditFieldValueChanged, true);
            app.omegaEditFieldLabel = uilabel(app.UIFigure);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.omegaEditField.ValueChangedFcn = createCallbackFcn(app, @omegaEditFieldValueChanged, true);
            app.PredictionnoiseLabel = uilabel(app.UIFigure);
            app.PredictionnoiseLabel.Text = 'Prediction noise';
            app.velocityEditField_2Label = uilabel(app.UIFigure);
            app.velocityEditField_2Label.Text = 'velocity';
            app.velocityEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.velocityEditField_2.ValueChangedFcn = createCallbackFcn(app, @velocityEditField_2ValueChanged, true);
            app.omegaEditField_2Label = uilabel(app.UIFigure);
            app.omegaEditField_2Label.Text = 'omega';
            app.omegaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.omegaEditField_2.ValueChangedFcn = createCallbackFcn(app, @omegaEditField_2ValueChanged, true);
            app.RobotPathDropDownLabel = uilabel(app.UIFigure);
            app.RobotPathDropDownLabel.Text = 'Robot Path';
            app.RobotPathDropDown = uidropdown(app.UIFigure);
            app.RobotPathDropDown.Items = {'1', '2'};
            app.RobotPathDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotPathDropDownValueChanged, true);
            app.RobotPathDropDown.Value = '1';
            app.NParticlesSliderLabel = uilabel(app.UIFigure);
            app.NParticlesSliderLabel.Text = 'NParticles';
            app.NParticlesSlider = uislider(app.UIFigure);
            app.NParticlesSlider.Limits = [1 10000];
            app.NParticlesSlider.MajorTicks = [1 1000 5000 10000];
            app.NParticlesSlider.ValueChangedFcn = createCallbackFcn(app, @NParticlesSliderValueChanged, true);
            app.NParticlesSlider.Value = 1000;
            app.ShowdiagnosticsCheckBox = uicheckbox(app.UIFigure);
            app.ShowdiagnosticsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowdiagnosticsCheckBoxValueChanged, true);
            app.ShowdiagnosticsCheckBox.Text = 'Show diagnostics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end